classdef MM_QueueApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MM1Tab                         matlab.ui.container.Tab
        Hyperlink                      matlab.ui.control.Hyperlink
        IntroduceivalorileLabel        matlab.ui.control.Label
        LambdareprezintratadesosireLabel  matlab.ui.control.Label
        MureprezintratadeservireLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        MuEditField_2                  matlab.ui.control.NumericEditField
        MuEditField_2Label             matlab.ui.control.Label
        LambdaEditField_2              matlab.ui.control.NumericEditField
        LambdaEditField_2Label         matlab.ui.control.Label
        RUNButton_2                    matlab.ui.control.Button
        MMmTab                         matlab.ui.container.Tab
        Hyperlink_2                    matlab.ui.control.Hyperlink
        IntroduceivalorileLabel_2      matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        MureprezintratadeservireLabel_2  matlab.ui.control.Label
        LambdareprezintratadesosireLabel_2  matlab.ui.control.Label
        ServerNumEditField             matlab.ui.control.NumericEditField
        ServerNumEditFieldLabel        matlab.ui.control.Label
        MuEditField_3                  matlab.ui.control.NumericEditField
        MuEditField_3Label             matlab.ui.control.Label
        LambdaEditField_3              matlab.ui.control.NumericEditField
        LambdaEditField_3Label         matlab.ui.control.Label
        RUNButton_3                    matlab.ui.control.Button
        MMmKTab                        matlab.ui.container.Tab
        Hyperlink_3                    matlab.ui.control.Hyperlink
        Image2_5                       matlab.ui.control.Image
        MaxreqreprezintanumrulmaximdecereriKLabel_2  matlab.ui.control.Label
        MureprezintratadeservireLabel_3  matlab.ui.control.Label
        LambdareprezintratadesosireLabel_3  matlab.ui.control.Label
        IntroduceivalorileLabel_3      matlab.ui.control.Label
        maxreqEditField                matlab.ui.control.NumericEditField
        maxreqEditFieldLabel           matlab.ui.control.Label
        ServerNumEditField_2           matlab.ui.control.NumericEditField
        ServerNumEditField_2Label      matlab.ui.control.Label
        MuEditField_4                  matlab.ui.control.NumericEditField
        MuEditField_4Label             matlab.ui.control.Label
        LambdaEditField_4              matlab.ui.control.NumericEditField
        LambdaEditField_4Label         matlab.ui.control.Label
        RUNButton_4                    matlab.ui.control.Button
        MM1KTab                        matlab.ui.container.Tab
        Hyperlink_4                    matlab.ui.control.Hyperlink
        MaxreqreprezintanumrulmaximdecereriKLabel  matlab.ui.control.Label
        Image2_3                       matlab.ui.control.Image
        MureprezintratadeservireLabel_4  matlab.ui.control.Label
        LambdareprezintratadesosireLabel_4  matlab.ui.control.Label
        IntroduceivalorileLabel_4      matlab.ui.control.Label
        maxreqEditField_2              matlab.ui.control.NumericEditField
        maxreqEditField_2Label         matlab.ui.control.Label
        MuEditField_5                  matlab.ui.control.NumericEditField
        MuEditField_5Label             matlab.ui.control.Label
        LambdaEditField_5              matlab.ui.control.NumericEditField
        LambdaEditField_5Label         matlab.ui.control.Label
        RUNButton_5                    matlab.ui.control.Button
        MMmmTab                        matlab.ui.container.Tab
        Hyperlink_5                    matlab.ui.control.Hyperlink
        Image2_4                       matlab.ui.control.Image
        MureprezintratadeservireLabel_5  matlab.ui.control.Label
        LambdareprezintratadesosireLabel_5  matlab.ui.control.Label
        IntroduceivalorileLabel_5      matlab.ui.control.Label
        ServerNumEditField_3           matlab.ui.control.NumericEditField
        ServerNumEditField_3Label      matlab.ui.control.Label
        MuEditField_6                  matlab.ui.control.NumericEditField
        MuEditField_6Label             matlab.ui.control.Label
        LambdaEditField_6              matlab.ui.control.NumericEditField
        LambdaEditField_6Label         matlab.ui.control.Label
        RUNButton_6                    matlab.ui.control.Button
        MMmKNTab                       matlab.ui.container.Tab
        Image2_6                       matlab.ui.control.Image
        NrPopEditField                 matlab.ui.control.NumericEditField
        NrPopEditFieldLabel            matlab.ui.control.Label
        maxreqEditField_3              matlab.ui.control.NumericEditField
        maxreqEditField_3Label         matlab.ui.control.Label
        ServerNumEditField_4           matlab.ui.control.NumericEditField
        ServerNumEditField_4Label      matlab.ui.control.Label
        MuEditField_7                  matlab.ui.control.NumericEditField
        MuEditField_7Label             matlab.ui.control.Label
        LambdaEditField_7              matlab.ui.control.NumericEditField
        LambdaEditField_7Label         matlab.ui.control.Label
        Hyperlink_6                    matlab.ui.control.Hyperlink
        MaxreqreprezintanumrulmaximdecereriKLabel_3  matlab.ui.control.Label
        MureprezintratadeservireLabel_6  matlab.ui.control.Label
        LambdareprezintratadesosireLabel_6  matlab.ui.control.Label
        IntroduceivalorileLabel_6      matlab.ui.control.Label
        RUNButton_7                    matlab.ui.control.Button
        MEr1Tab                        matlab.ui.container.Tab
        Image_2                        matlab.ui.control.Image
        IntroduceivalorileLabel_7      matlab.ui.control.Label
        MureprezintratadeservireLabel_7  matlab.ui.control.Label
        rreprezintanumaruldefazeErlangLabel  matlab.ui.control.Label
        rEditField                     matlab.ui.control.NumericEditField
        rEditFieldLabel                matlab.ui.control.Label
        MuEditField_8                  matlab.ui.control.NumericEditField
        MuEditField_8Label             matlab.ui.control.Label
        LambdaEditField_8              matlab.ui.control.NumericEditField
        LambdaEditField_8Label         matlab.ui.control.Label
        Hyperlink_7                    matlab.ui.control.Hyperlink
        LambdareprezintratadesosireLabel_7  matlab.ui.control.Label
        RUNButton_8                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            for i = 2:8

                buttonName = sprintf('RUNButton_%d', i);

                % Aplica stilul pentru fiecare buton
                app.(buttonName).BackgroundColor = [0.4, 0.7, 0.2];
                app.(buttonName).FontSize = 14;
                app.(buttonName).FontWeight = 'bold';
                app.(buttonName).Text = 'RUN';
            end


        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)

            lambda = app.LambdaEditField_2.Value;
            mu = app.MuEditField_2.Value;
            ServerNumbers = 1;
            MaxReq = inf;
            % Verificăm validitatea valorilor introduse.
            if lambda < 0 || mu <= lambda
                uialert(app.UIFigure, 'Lambda trebuie sa fie > 0 și mu > Lambda!', 'Eroare');
                return;
            end

            % Apelăm funcția pentru a calcula parametrii.
            [U, R, Q,Q_queue, X, p0] = Perf_M_M_1(lambda, mu);

            % Creăm o fereastră nouă pentru afișarea rezultatelor.
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f'], ...
                U * 100, R, Q_queue, X, p0);

            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');
            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'ServerNumbers', ServerNumbers);
            assignin('base','MaxReq',MaxReq);
            assignin('base','Q_queue',Q_queue);
            % Deschide modelul Simulink
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('mm1_2023'));
            % Graficul de comparare
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_K'));

            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_K'));

        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)

            lambda = app.LambdaEditField_3.Value;
            mu = app.MuEditField_3.Value;
            m = app.ServerNumEditField.Value;
            MaxReq = inf;
            rho = lambda/(mu*m);
            % Verificăm validitatea valorilor introduse.
            if lambda < 0 || mu < 0 || rho > 1
                uialert(app.UIFigure, 'Lambda and mu must be greater than 0, and rho must be smaller than 1!', 'Eroare');
                return;
            end

            % Apelăm funcția pentru a calcula parametrii.
            [U, R, Q,Q_queue, X, p0, C] = Perf_M_M_m(lambda, mu, m);

            % Creăm o fereastră nouă pentru afișarea rezultatelor.
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.4f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Queue Probability (C Erlang): %.4f'], ...
                U * 100, R, Q_queue, X, p0,C);

            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');
            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'ServerNumbers', m);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base','MaxReq',MaxReq);
            assignin('base','Q_queue',Q_queue);

            % Graficul de comparare
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_K'));

            % Deschide modelul Simulink
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_K'));

        end

        % Button pushed function: RUNButton_4
        function RUNButton_4Pushed(app, event)

            lambda = app.LambdaEditField_4.Value;
            mu = app.MuEditField_4.Value;
            m = app.ServerNumEditField_2.Value;
            K = app.maxreqEditField.Value;

            % Verificăm validitatea valorilor introduse.
            if lambda < 0 || mu <= 0
                uialert(app.UIFigure, 'Lambda and mu must be greater than 0', 'Eroare');
                return;
            end


            % Apelăm funcția pentru a calcula parametrii.
            [U, R, Q,Q_queue ,X, p0, pK] = Perf_M_M_m_K(lambda, mu, m, K);

            % Creăm o fereastră nouă pentru afișarea rezultatelor.
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Full system probability (pK): %.4f'], ...
                U * 100, R, Q_queue, X, p0, pK);


            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'pK', pK);
            assignin('base', 'MaxReq',K);
            assignin('base','ServerNumbers',m);
            assignin('base','Q_queue',Q_queue);
            % Graficul de comparare
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_K'));

            % Deschide modelul Simulink
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_K'));

        end

        % Button pushed function: RUNButton_5
        function RUNButton_5Pushed(app, event)

            lambda = app.LambdaEditField_5.Value;
            mu = app.MuEditField_5.Value;
            K = app.maxreqEditField_2.Value;
            ServerNumbers = 1;
            % Verificăm validitatea valorilor introduse.
            if lambda < 0 || mu < 0 || K <= 1
                uialert(app.UIFigure, 'Lambda and mu must be greater than 0 and MaxReq greater than 1!', 'Eroare');
                return;
            end
            

            % Apelăm funcția pentru a calcula parametrii.
            [U, R, Q, Q_queue, X, p0, pK] = Perf_M_M_1_K(lambda, mu, K);

            % Creăm o fereastră nouă pentru afișarea rezultatelor.
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Full system probability (pK): %.4f'], ...
                U * 100, R, Q_queue, X, p0, pK);

            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'pK', pK);
            assignin('base', 'MaxReq',K);
            assignin('base', 'ServerNumbers', ServerNumbers);
            assignin('base', 'Q_queue', Q_queue);

            % Graficul de comparare
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_K'));

            % Deschide modelul Simulink
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_K'));

        end

        % Button pushed function: RUNButton_6
        function RUNButton_6Pushed(app, event)

            lambda = app.LambdaEditField_6.Value;
            mu = app.MuEditField_6.Value;
            m = app.ServerNumEditField_3.Value;
            MaxReq = inf;
            % Verificăm validitatea valorilor introduse.
            if lambda < 0 || mu < 0
                uialert(app.UIFigure, 'Lambda and mu must be greater than 0!', 'Eroare');
                return;
            end

            % Apelăm funcția pentru a calcula parametrii.
            [U, R, Q,Q_queue, X, p0, pB] = Perf_M_M_m_m(lambda, mu, m);

            % Creăm o fereastră nouă pentru afișarea rezultatelor.
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Blocking probabbility (B Erlang): %.4f'], ...
                U * 100, R, Q_queue, X, p0,pB);

            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');
            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'ServerNumbers', m);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'MaxReq',MaxReq);
            assignin('base', 'Q_queue',Q_queue);
            % Graficul de comparare
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_m'));

            % Deschide modelul Simulink
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_m'));

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)

            helpFig = uifigure('Name', 'M/M/1', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/1 este cel mai simplu model de coadă, având un singur server și ' ...
                'un fir de așteptare infinit. Clienții sosesc conform unui proces Poisson cu rată λ, ' ...
                'iar timpul de servire este exponențial cu parametru μ. Servirea urmează politica FIFO.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Fluxul de sosire este un proces Poisson cu rată λ.', newline, ...
                '- Durata de servire este exponențială cu rată μ.', newline, ...
                '- Sistemul are un singur server (m = 1).', newline, ...
                '- Firul de așteptare este infinit (K = ∞).', newline, ...
                '- Servirea se face FIFO.'], ...
                'FontSize', 16, 'Position', [20, 560, 370, 80], 'WordWrap', 'on');

            % Imaginea cu firul de așteptare
            uiimage(helpFig, 'ImageSource', 'M_M_1_poza_nou.jpg', 'Position', [400, 550, 320, 100]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 530, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 260, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Intensitatea traficului:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho = \frac{\lambda}{\mu}, \quad 0 < \rho < 1$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Gradul de utilizare (condiție de stabilitate):', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho_s = \frac{\lambda}{\mu}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Numărul mediu de clienți în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$L = \frac{\lambda}{\mu - \lambda}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu petrecut în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W = \frac{1}{\mu - \lambda}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu de așteptare în coadă:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W_q = \frac{\lambda}{\mu(\mu - \lambda)}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de așteptare:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$P_Q = \rho$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Titlu distribuție
            uilabel(helpFig, 'Text', '➤ Distribuția numărului de clienți în sistem:', ...
                'FontSize', 13, 'FontWeight', 'bold', 'Position', [350, 530, 700, 20]);

            % Axe pentru distribuții
            distAxes = uiaxes(helpFig, 'Position', [350, 455, 700, 80]);
            distAxes.Visible = 'off';
            distAxes.XColor = 'none';
            distAxes.YColor = 'none';
            distAxes.XLim = [0 1];
            distAxes.YLim = [0 1];
            distAxes.HitTest = 'off';
            distAxes.PickableParts = 'none';
            distAxes.Interactions = [];

            yd = 0.85;
            text(distAxes, 0, yd, '- Probabilitatea ca sistemul să fie gol:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yd = yd - 0.4;
            text(distAxes, 0.02, yd, '$\pi_0 = 1 - \rho$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            yd = yd - 0.7;
            text(distAxes, 0, yd, '- Distribuția numărului de clienți:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yd = yd - 0.4;
            text(distAxes, 0.02, yd, '$\pi_n = \rho^n (1 - \rho), \quad n \geq 1$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Imaginea lanțului Markov (jos, centrat)
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_1.jpg', 'Position', [180, 200, 650, 100]);

            % Buton de închidere
            uibutton(helpFig, 'push', 'Text', 'Close', 'Position', [320, 30, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Callback function: Hyperlink_2
        function Hyperlink_2Clicked(app, event)
            
            helpFig = uifigure('Name', 'M/M/m', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/m este o extensie a modelului M/M/1, având acum m servere identice ' ...
                'care procesează clienții în paralel. Clienții sosesc conform unui proces Poisson cu rată λ, ' ...
                'iar timpul de servire este exponențial cu parametru μ pentru fiecare server. ' ...
                'Servirea urmează politica FIFO, iar firul de așteptare este infinit.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Fluxul de sosire este un proces Poisson cu rată λ.', newline, ...
                '- Sistemul are m servere identice care funcționează simultan.', newline, ...
                '- Durata de servire a fiecărui client este exponențială cu parametru μ.', newline, ...
                '- Firul de așteptare este infinit (K = ∞).', newline, ...
                '- Servirea se face FIFO.'], ...
                'FontSize', 16, 'Position', [20, 560, 370, 80], 'WordWrap', 'on');

            % Imaginea cu firul de așteptare
            uiimage(helpFig, 'ImageSource', 'M_M_m_poza.jpg', 'Position', [400, 550, 320, 100]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 530, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 250, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Intensitatea traficului:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho = \frac{\lambda}{m\mu}, \quad 0 < \rho < 1$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Gradul de utilizare al unui server:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho_s = \frac{\rho}{m}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Numărul mediu de clienți în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$L = L_q + \frac{\lambda}{\mu}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu petrecut în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W = \frac{L}{\lambda}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu de așteptare în coadă:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W_q = \frac{L_q}{\lambda}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de așteptare (Erlang-C):', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$P_Q = \frac{(m\rho)^m}{m!(1 - \rho)} \cdot \pi_0$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Titlu analiza firului
            uilabel(helpFig, 'Text', '➤ Analiza firului de așteptare:', ...
                'FontSize', 13, 'FontWeight', 'bold', 'Position', [350, 530, 700, 20]);

            % Axe pentru analiza cozii
            queueAxes = uiaxes(helpFig, 'Position', [350, 445, 700, 80]);
            queueAxes.Visible = 'off';
            queueAxes.XColor = 'none';
            queueAxes.YColor = 'none';
            queueAxes.XLim = [0 1];
            queueAxes.YLim = [0 1];
            queueAxes.HitTest = 'off';
            queueAxes.PickableParts = 'none';
            queueAxes.Interactions = [];

            yq = 0.85;
            dyq = 0.5;

            text(queueAxes, 0, yq, '- Numărul mediu de clienți care așteaptă:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yq = yq - 0.6;
            text(queueAxes, 0.02, yq, '$L_q = \frac{(m\rho)^m}{m!(1 - \rho)^2} \cdot \pi_0$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            yq = yq - dyq;
            text(queueAxes, 0.02, yq, '- Timpul mediu de așteptare:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yq = yq - 0.6;
            text(queueAxes, 0.02, yq, '$W_q = \frac{L_q}{\lambda}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            yq = yq - dyq;
            text(queueAxes, 0.02, yq, '- Probabilitatea ca timpul de așteptare să depășească t:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yq = yq - 0.6;
            text(queueAxes, 0.02, yq, '$P(W > t) = P_Q \cdot e^{-m(\mu - \lambda)t}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Imaginea lanțului Markov
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_m.jpg', 'Position', [180, 200, 650, 100]);

            % Buton de închidere
            uibutton(helpFig, 'push', 'Text', 'Close', 'Position', [320, 20, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Callback function: Hyperlink_3
        function Hyperlink_3Clicked(app, event)
          
            helpFig = uifigure('Name', 'M/M/m/K', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/m/K este o extensie a M/M/m, având un număr maxim de K clienți ' ...
                'în sistem. Dacă firul de așteptare și serverele sunt pline, noii clienți sunt respinși.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Fluxul de sosire este un proces Poisson cu rată λ.', newline, ...
                '- Sistemul are m servere identice.', newline, ...
                '- Firul de așteptare are o capacitate finită de K - m.', newline, ...
                '- Dacă sistemul este plin (K clienți), clienții noi sunt respinși.', newline, ...
                '- Servirea este exponențială cu parametru μ.'], ...
                'FontSize', 16, 'Position', [20, 560, 370, 80], 'WordWrap', 'on');

            % Imaginea cu firul de așteptare
            uiimage(helpFig, 'ImageSource', 'M_M_m_K_poza.jpg', 'Position', [400, 550, 320, 100]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 530, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 250, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Distribuția pentru 0 ≤ n ≤ m:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\pi_n = \frac{\rho^n}{n!} \cdot \pi_0$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Distribuția pentru m < n ≤ K:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\pi_n = \frac{\rho^n}{m! \cdot m^{n - m}} \cdot \pi_0$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea ca sistemul să fie gol:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\pi_0 = \left[ \sum_{n=0}^{m} \frac{\rho^n}{n!} + \sum_{n=m+1}^{K} \frac{\rho^n}{m! \cdot m^{n - m}} \right]^{-1}$', ...
                'Interpreter', 'latex', 'FontSize', 13, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de blocare (Erlang-B modificat):', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$P_B = \pi_K$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Rata efectivă de intrare în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\lambda_e = \lambda (1 - P_B)$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Numărul mediu de clienți în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$M[X] = \sum_{n=0}^K n \cdot \pi_n$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu petrecut în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W = \frac{M[X]}{\lambda_e}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu de așteptare în coadă:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W_q = \frac{M[X] - (1 - P_B)\cdot \frac{\lambda}{\mu}}{\lambda_e}$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');

            % Titlu probabilitate blocare
            uilabel(helpFig, 'Text', '➤ Probabilitatea de blocare (Erlang-B modificat):', ...
                'FontSize', 13, 'FontWeight', 'bold', 'Position', [350, 530, 700, 20]);

            % Axe pentru explicații blocare
            blocareAxes = uiaxes(helpFig, 'Position', [350, 445, 700, 80]);
            blocareAxes.Visible = 'off';
            blocareAxes.XColor = 'none';
            blocareAxes.YColor = 'none';
            blocareAxes.XLim = [0 1];
            blocareAxes.YLim = [0 1];
            blocareAxes.HitTest = 'off';
            blocareAxes.PickableParts = 'none';
            blocareAxes.Interactions = [];

            yb = 0.85;
            dyb = 0.5;

            text(blocareAxes, 0, yb, '- Formula Erlang-B modificată:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            yb = yb - 0.6;
            text(blocareAxes, 0.02, yb, '$P_B(m, K, \rho) = \pi_K = \frac{\rho^K}{m! \cdot m^{K - m}} \cdot \pi_0$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            yb = yb - dyb;
            text(blocareAxes, 0.02, yb, '- Afectează rata efectivă de intrare în sistem.', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yb = yb - 0.4;
            text(blocareAxes, 0.02, yb, '- Crește pe măsură ce K scade.', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');

            % Imaginea lanțului Markov
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_m_K.jpg', 'Position', [180, 150, 650, 100]);

            % Buton de închidere
            uibutton(helpFig, 'push', 'Text', 'Close', 'Position', [320, 20, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Callback function: Hyperlink_4
        function Hyperlink_4Clicked(app, event)
           
            helpFig = uifigure('Name', 'M/M/1/K', 'Position', [400, 100, 750, 750],'Color',[1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/1/K este o extensie a modelului M/M/1, dar cu o capacitate ' ...
                'maximă de K clienți. Dacă numărul maxim de clienți este atins, noii clienți ' ...
                'sunt respinși și nu intră în sistem.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Fluxul de sosire este un proces Poisson cu rată λ.', newline, ...
                '- Sistemul are un singur server (m = 1).', newline, ...
                '- Durata de servire este exponențială cu parametru μ.', newline, ...
                '- Capacitatea maximă a sistemului este K.', newline, ...
                '- Dacă sistemul este plin, clienții sunt respinși.'], ...
                'FontSize', 17, 'Position', [20, 570, 700, 80], 'WordWrap', 'on');

            % Titlu formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 540, 700, 20]);

            % Axă invizibilă pentru formule importante cu explicații
            formuleAxes = uiaxes(helpFig, 'Position', [20, 265, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            % Scrierea formulelor LaTeX cu explicații
            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Probabilitatea ca sistemul să fie gol:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$\pi_0 = \frac{1 - \rho}{1 - \rho^{K+1}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Distribuția numărului de clienți în sistem:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$\pi_n = \rho^n \cdot \pi_0,\quad n = 1, 2, \dots, K$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de blocare (Erlang-B):', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$P_B = \frac{(1 - \rho) \cdot \rho^K}{1 - \rho^{K+1}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Numărul mediu de clienți în sistem:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$M[X] = \frac{\rho}{1 - \rho} - \frac{K \cdot \rho^{K+1}}{1 - \rho^{K+1}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu petrecut în sistem:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$W = \frac{M[X]}{\lambda_{\mathrm{eff}}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu de așteptare în coadă:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            y = y - 0.125;
            text(formuleAxes, 0.02, y, '$W_q = \frac{M[X] - (1 - P_B)\cdot \frac{\lambda}{\mu}}{\lambda_{\mathrm{eff}}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            % Analiza firului de așteptare
            uilabel(helpFig, 'Text', '➤ Analiza firului de așteptare:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [350, 540, 700, 20]);

            % Axă pentru formulele din analiza cozii
            queueAxes = uiaxes(helpFig, 'Position', [350, 470, 700, 80]);
            queueAxes.Visible = 'off';
            queueAxes.XColor = 'none';
            queueAxes.YColor = 'none';
            queueAxes.XLim = [0 1];
            queueAxes.YLim = [0 1];
            queueAxes.HitTest = 'off';
            queueAxes.PickableParts = 'none';
            queueAxes.Interactions = [];

            yq = 0.65;
            dyq = 0.65;

            text(queueAxes, 0, yq, '- Numărul mediu de clienți care așteaptă în coadă:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            yq = yq - 0.5;
            text(queueAxes, 0.02, yq, '$M[X_Q] = M[X] - \rho$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            yq = yq - dyq;
            text(queueAxes, 0, yq, '- Timpul mediu de așteptare în coadă:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            yq = yq - 0.5;
            text(queueAxes, 0.02, yq, '$W_q = \frac{M[X_Q]}{\lambda_{\mathrm{eff}}}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            yq = yq - dyq;
            text(queueAxes, 0, yq, '- Probabilitatea ca timpul de așteptare să depășească t:', ...
                'FontSize', 12, 'PickableParts', 'none', 'HorizontalAlignment', 'left');
            yq = yq - 0.5;
            text(queueAxes, 0.02, yq, '$P(W > t) = P_B \cdot e^{-\mu (1 - P_B) \cdot t}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'PickableParts', 'none', 'HorizontalAlignment', 'left');

            % Imagini explicative
            uiimage(helpFig, 'ImageSource', 'M_M_1_K_poza.jpg', 'Position', [280, 570, 600,110]);
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_1_K.jpg', 'Position', [180, 200, 650, 100]);

            % Buton de închidere
            uibutton(helpFig, 'push', 'Text', 'Close', 'Position', [320, 30, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Callback function: Hyperlink_5
        function Hyperlink_5Clicked(app, event)
         
            helpFig = uifigure('Name', 'M/M/m/m', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/m/m este o variantă a M/M/m, unde numărul maxim de clienți ' ...
                'în sistem este limitat la m, adică exact numărul de servere disponibile. ' ...
                'Dacă toate serverele sunt ocupate, clienții noi sunt respinși.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Fluxul de sosire este un proces Poisson cu rată λ.', newline, ...
                '- Sistemul are m servere identice.', newline, ...
                '- Nu există fir de așteptare.', newline, ...
                '- Capacitatea sistemului este exact m.', newline, ...
                '- Clienții care găsesc toate serverele ocupate sunt respinși.'], ...
                'FontSize', 16, 'Position', [20, 560, 370, 80], 'WordWrap', 'on');

            % Imaginea cu firul de așteptare
            uiimage(helpFig, 'ImageSource', 'M_M_m_m_poza.jpg', 'Position', [400, 550, 320, 100]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 530, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 250, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Distribuția numărului de clienți în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, ...
                '$\pi_n = \frac{(\rho^n / n!)}{\sum_{k=0}^m (\rho^k / k!)},\quad n = 0,1,\dots,m$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');


            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de blocare (Erlang-B):', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$P_B = \frac{\rho^m / m!}{\sum_{k=0}^m \rho^k / k!}$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Rata efectivă de intrare în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\lambda_e = \lambda (1 - P_B)$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Numărul mediu de clienți în sistem:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$M[X] = \frac{\lambda_e}{\mu}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Gradul de utilizare al serverelor:', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho_s = \frac{M[X]}{m}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Titlu blocare
            uilabel(helpFig, 'Text', '➤ Probabilitatea de blocare (Erlang-B):', ...
                'FontSize', 13, 'FontWeight', 'bold', 'Position', [350, 530, 700, 20]);

            % Axe explicații blocare
            blocareAxes = uiaxes(helpFig, 'Position', [350, 445, 700, 80]);
            blocareAxes.Visible = 'off';
            blocareAxes.XColor = 'none';
            blocareAxes.YColor = 'none';
            blocareAxes.XLim = [0 1];
            blocareAxes.YLim = [0 1];
            blocareAxes.HitTest = 'off';
            blocareAxes.PickableParts = 'none';
            blocareAxes.Interactions = [];

            yb = 0.85;
            dyb = 0.5;

            text(blocareAxes, 0, yb, '- Formula Erlang-B clasică:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            yb = yb - 0.6;
            text(blocareAxes, 0.02, yb, '$P_B(m, \rho) = \frac{\rho^m / m!}{\sum_{k=0}^m \rho^k / k!}$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');

            yb = yb - dyb;
            text(blocareAxes, 0.02, yb, '- Afectează rata efectivă de intrare în sistem.', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');
            yb = yb - 0.4;
            text(blocareAxes, 0.02, yb, '- Crește odată cu gradul de ocupare.', ...
                'FontSize', 12, 'HorizontalAlignment', 'left');

            % Imaginea lanțului Markov
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_m_m.jpg', 'Position', [180, 230, 650, 100]);

            % Buton închidere
            uibutton(helpFig, 'push', 'Text', 'Close', 'Position', [320, 20, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Button pushed function: RUNButton_7
        function RUNButton_7Pushed(app, event)
           
            lambda = app.LambdaEditField_7.Value;
            mu = app.MuEditField_7.Value;
            m = app.ServerNumEditField_4.Value;
            K = app.maxreqEditField_3.Value;
            NrPop =app.NrPopEditField.Value;
            MaxReq = app.maxreqEditField_3.Value;
            % We check the validity of the inputs.
            if lambda < 0 || mu <= lambda
                uialert(app.UIFigure, 'Lambda trebuie sa mie > 0 și mu > Lambda', 'Eroare');
                return;
            end


            % We call the Perf_M_M_m_K function to calculate the parameters.
            [U, R, Q,Q_queue, X, p0, pK] = Perf_M_M_m_K_N(lambda, mu, m, K, NrPop);

            % Create a new window for the results
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            % Create a formatted string with the results
            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Full system probability (pK): %.4f'], ...
                U * 100, R, Q, X, p0, pK);


            % Place a UILabel for the resulting text in the results window
            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

            % Assign values to base workspace
            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'pK', pK);
            assignin('base', 'MaxReq',K);
            assignin('base',"NrPop",NrPop);
            assignin('base','ServerNumbers',m);
            assignin('base','MaxReq',MaxReq);
            assignin('base','Q_queue',Q_queue);
            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_M_m_K_N'));

            % Buton: Open Simulink Model
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_M_m_K_N'));

        end

        % Callback function: Hyperlink_6
        function Hyperlink_6Clicked(app, event)
           
            helpFig = uifigure('Name', 'M/M/m/K/N', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/M/m/K/N modelează o coadă cu m servere paralele, ' ...
                'capacitate totală K și o populație finită de clienți N. ' ...
                'Rata de sosire depinde de numărul de clienți disponibili (care nu sunt deja în sistem).'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Rata de sosire este Poisson cu λ, dar variază: λₙ = λ (N - n)', newline, ...
                '- m servere identice funcționează simultan.', newline, ...
                '- Servirea este exponențială cu parametru μ.', newline, ...
                '- Capacitatea totală a sistemului este K (servere + coadă).', newline, ...
                '- Dacă în sistem sunt deja K clienți, alții sunt respinși.', ...
                newline, '- Populația totală de clienți este N.'], ...
                'FontSize', 15, 'Position', [20, 530, 800, 110], 'WordWrap', 'on');

            % Placeholder imagine sus dreapta
            uiimage(helpFig, 'ImageSource', 'M_M_m_K_N_poza.jpg', 'Position', [400, 250, 250, 300]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 500, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 230, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Rata de sosire variabilă:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\lambda_n = \lambda \cdot (N - n)$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Rata de servire:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\mu_n = \min(n, m) \cdot \mu$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Distribuția staționară:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\pi_n = \pi_0 \cdot \prod_{k=1}^{n} \frac{\lambda_{k-1}}{\mu_k}$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Probabilitatea de blocare:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$P_B = \pi_K$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Throughput efectiv:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$X = \lambda \cdot (1 - P_B)$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timpul mediu de răspuns:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$R = \frac{Q}{X}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Placeholder imagine lanț Markov
            uiimage(helpFig, 'ImageSource', 'Lant_M_M_m_K_N.jpg', 'Position', [180, 100, 650, 100]);

            % Buton închidere
            uibutton(helpFig, 'push', 'Text', 'Close', ...
                'Position', [320, 20, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end

        % Button pushed function: RUNButton_8
        function RUNButton_8Pushed(app, event)
           
            lambda = app.LambdaEditField_8.Value;
            mu = app.MuEditField_8.Value;
            MaxReq = inf;
            ServerNumbers = 1;
            r = app.rEditField.Value;
            % We check the validity of the inputs.
            if lambda < 0 || mu <= lambda
                uialert(app.UIFigure, 'Lambda must be greater than 0, and mu must be greater than lambda!', 'Eroare');
                return;
            end
            
            rho = (lambda * r) / mu;

            if rho >= 1
                uialert(app.UIFigure, ...
                    sprintf('Sistem instabil!\nρ = %.2f ≥ 1\nAlegeți valori mai mici pentru λ sau r, sau mai mari pentru μ.', rho), ...
                    'Instabilitate');
                return;
            end

            % We call the Perf_M_M_m_K function to calculate the parameters.
            [U, R, Q,Q_queue, X, p0, pK] = Perf_M_Er_1(lambda, mu, r);

            % Create a new window for the results
            resultFig = uifigure('Name', 'Performance Results', 'Position', [100, 100, 400, 300]);

            % Create a formatted string with the results
            resultText = sprintf(['Utilization: %.2f %%\n', ...
                'Response Time: %.4f sec\n', ...
                'Queue Length: %.2f requests\n', ...
                'Throughput: %.2f jobs/sec\n', ...
                'Idle Probability (p0): %.4f\n', ...
                'Full system probability (pK): %.4f'], ...
                U * 100, R, Q, X, p0, pK);


            % Place a UILabel for the resulting text in the results window
            uilabel(resultFig, 'Position', [10, 50, 380, 200], 'Text', resultText, ...
                'FontSize', 16, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

            % Assign values to base workspace (optional)
            assignin('base', 'lambda', lambda);
            assignin('base', 'mu', mu);
            assignin('base', 'U', U);
            assignin('base', 'R', R);
            assignin('base', 'Q', Q);
            assignin('base', 'X', X);
            assignin('base', 'p0', p0);
            assignin('base', 'pK', pK);
            assignin('base', 'MaxReq',MaxReq);
            assignin('base', 'ServerNumbers', ServerNumbers);
            assignin('base','r',r);
            assignin('base','rho',rho);
            assignin('base','Q_queue',Q_queue);

            uibutton(resultFig, 'Text', 'Show Comparison Graph', ...
                'Position', [100, 20, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) launch_comparison_window('M_Er_1'));

            % Buton: Open Simulink Model
            uibutton(resultFig, 'Text', 'Open Simulink Model', ...
                'Position', [100, 60, 200, 30], ...
                'ButtonPushedFcn', @(btn, event) open_system('M_Er_1'));

        end

        % Callback function: Hyperlink_7
        function Hyperlink_7Clicked(app, event)

            helpFig = uifigure('Name', 'M/Er/1', 'Position', [400, 100, 750, 750], 'Color', [1 1 1]);

            % Descriere scurtă
            uilabel(helpFig, 'Text', ...
                ['Sistemul M/Er/1 reprezintă o coadă cu un singur server, în care sosirile sunt Poisson cu rată λ, ' ...
                'iar timpul de servire este Erlang de ordin r – adică fiecare client trece prin r faze de servire exponențiale. ' ...
                'Acest model reduce variația timpului de servire față de cazul exponențial clasic.'], ...
                'FontSize', 17, 'Position', [20, 680, 700, 60], 'WordWrap', 'on');

            % Caracteristici generale
            uilabel(helpFig, 'Text', '➤ Caracteristici generale:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 650, 700, 20]);

            uilabel(helpFig, 'Text', ...
                ['- Sosiri: proces Poisson cu rată λ.', newline, ...
                '- Servicii: distribuție Erlang-r (suma a r faze exponențiale).', newline, ...
                '- Timpul mediu de servire: r / μ.', newline, ...
                '- Coeficientul de variație al timpului de servire: c_B² = 1 / r.', newline, ...
                '- Politica de servire: FIFO (primul venit, primul servit).', newline, ...
                '- Sistemul are un singur server și coadă infinită.'], ...
                'FontSize', 15, 'Position', [20, 530, 800, 110], 'WordWrap', 'on');

            % Imagine reprezentativă sus dreapta
            uiimage(helpFig, 'ImageSource', 'M_M_1_poza_nou.jpg', 'Position', [350, 350, 320, 150]);

            % Titlu Formule importante
            uilabel(helpFig, 'Text', '➤ Formule importante:', 'FontSize', 13, 'FontWeight', 'bold', ...
                'Position', [20, 500, 700, 20]);

            % Axe pentru formule
            formuleAxes = uiaxes(helpFig, 'Position', [20, 230, 700, 270]);
            formuleAxes.Visible = 'off';
            formuleAxes.XColor = 'none';
            formuleAxes.YColor = 'none';
            formuleAxes.XLim = [0 1];
            formuleAxes.YLim = [0 1];
            formuleAxes.HitTest = 'off';
            formuleAxes.PickableParts = 'none';
            formuleAxes.Interactions = [];

            y = 0.98;
            dy = 0.13;

            text(formuleAxes, 0, y, '- Utilizare (stabilitate dacă ρ < 1):', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$\rho = \frac{\lambda \cdot r}{\mu}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Coeficient variație timp servire:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$c_B^2 = \frac{1}{r}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timp mediu de așteptare în coadă:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$W_q = \frac{\rho^2}{\lambda (1 - \rho)} \cdot \frac{1 + c_B^2}{2}$', ...
                'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Timp total în sistem:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$R = W_q + \frac{r}{\mu}$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            y = y - dy;
            text(formuleAxes, 0, y, '- Număr mediu de clienți în sistem:', 'FontSize', 12, 'HorizontalAlignment', 'left');
            y = y - 0.10;
            text(formuleAxes, 0.02, y, '$Q = \lambda \cdot R$', ...
                'Interpreter', 'latex', 'FontSize', 15, 'HorizontalAlignment', 'left');

            % Imagine lanț Markov jos
            uiimage(helpFig, 'ImageSource', 'Lant_M_Er_1.jpg', 'Position', [180, 200, 650, 100]);

            % Buton închidere
            uibutton(helpFig, 'push', 'Text', 'Close', ...
                'Position', [320, 20, 100, 30], ...
                'ButtonPushedFcn', @(src, event) close(helpFig));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MM1Tab = uitab(app.TabGroup);
            app.MM1Tab.Title = 'M/M/1';
            app.RUNButton_2 = uibutton(app.MM1Tab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.LambdaEditField_2Label = uilabel(app.MM1Tab);
            app.LambdaEditField_2Label.Text = 'Lambda';
            app.LambdaEditField_2 = uieditfield(app.MM1Tab, 'numeric');
            app.MuEditField_2Label = uilabel(app.MM1Tab);
            app.MuEditField_2Label.Text = 'Mu';
            app.MuEditField_2 = uieditfield(app.MM1Tab, 'numeric');
            app.Image = uiimage(app.MM1Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_1_poza_nou.jpg');
            app.MureprezintratadeservireLabel = uilabel(app.MM1Tab);
            app.MureprezintratadeservireLabel.Text = 'Mu (μ) reprezintă rata de servire.';
            app.LambdareprezintratadesosireLabel = uilabel(app.MM1Tab);
            app.LambdareprezintratadesosireLabel.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.IntroduceivalorileLabel = uilabel(app.MM1Tab);
            app.IntroduceivalorileLabel.Text = 'Introduceți valorile';
            app.Hyperlink = uihyperlink(app.MM1Tab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Tag = 'HelpLabel_MM1';
            app.Hyperlink.Text = 'Help';
            app.MMmTab = uitab(app.TabGroup);
            app.MMmTab.Title = 'M/M/m';
            app.RUNButton_3 = uibutton(app.MMmTab, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Text = 'RUN';
            app.LambdaEditField_3Label = uilabel(app.MMmTab);
            app.LambdaEditField_3Label.Text = 'Lambda';
            app.LambdaEditField_3 = uieditfield(app.MMmTab, 'numeric');
            app.MuEditField_3Label = uilabel(app.MMmTab);
            app.MuEditField_3Label.Text = 'Mu';
            app.MuEditField_3 = uieditfield(app.MMmTab, 'numeric');
            app.ServerNumEditFieldLabel = uilabel(app.MMmTab);
            app.ServerNumEditFieldLabel.Text = 'ServerNum';
            app.ServerNumEditField = uieditfield(app.MMmTab, 'numeric');
            app.LambdareprezintratadesosireLabel_2 = uilabel(app.MMmTab);
            app.LambdareprezintratadesosireLabel_2.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.MureprezintratadeservireLabel_2 = uilabel(app.MMmTab);
            app.MureprezintratadeservireLabel_2.Text = 'Mu (μ) reprezintă rata de servire.';
            app.Image2 = uiimage(app.MMmTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_m_poza.jpg');
            app.IntroduceivalorileLabel_2 = uilabel(app.MMmTab);
            app.IntroduceivalorileLabel_2.Text = 'Introduceți valorile';
            app.Hyperlink_2 = uihyperlink(app.MMmTab);
            app.Hyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_2Clicked, true);
            app.Hyperlink_2.Tag = 'HelpLabel_MMm';
            app.Hyperlink_2.Text = 'Help';
            app.MMmKTab = uitab(app.TabGroup);
            app.MMmKTab.Title = 'M/M/m/K';
            app.RUNButton_4 = uibutton(app.MMmKTab, 'push');
            app.RUNButton_4.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_4Pushed, true);
            app.RUNButton_4.Text = 'RUN';
            app.LambdaEditField_4Label = uilabel(app.MMmKTab);
            app.LambdaEditField_4Label.Text = 'Lambda';
            app.LambdaEditField_4 = uieditfield(app.MMmKTab, 'numeric');
            app.MuEditField_4Label = uilabel(app.MMmKTab);
            app.MuEditField_4Label.Text = 'Mu';
            app.MuEditField_4 = uieditfield(app.MMmKTab, 'numeric');
            app.ServerNumEditField_2Label = uilabel(app.MMmKTab);
            app.ServerNumEditField_2Label.Text = 'ServerNum';
            app.ServerNumEditField_2 = uieditfield(app.MMmKTab, 'numeric');
            app.maxreqEditFieldLabel = uilabel(app.MMmKTab);
            app.maxreqEditFieldLabel.Text = 'max req';
            app.maxreqEditField = uieditfield(app.MMmKTab, 'numeric');
            app.IntroduceivalorileLabel_3 = uilabel(app.MMmKTab);
            app.IntroduceivalorileLabel_3.Text = 'Introduceți valorile';
            app.LambdareprezintratadesosireLabel_3 = uilabel(app.MMmKTab);
            app.LambdareprezintratadesosireLabel_3.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.MureprezintratadeservireLabel_3 = uilabel(app.MMmKTab);
            app.MureprezintratadeservireLabel_3.Text = 'Mu (μ) reprezintă rata de servire.';
            app.MaxreqreprezintanumrulmaximdecereriKLabel_2 = uilabel(app.MMmKTab);
            app.MaxreqreprezintanumrulmaximdecereriKLabel_2.Text = 'Max req reprezinta numărul maxim de cereri (K)';
            app.Image2_5 = uiimage(app.MMmKTab);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_m_K_poza.jpg');
            app.Hyperlink_3 = uihyperlink(app.MMmKTab);
            app.Hyperlink_3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_3Clicked, true);
            app.Hyperlink_3.Tag = 'HelpLabel_MMmK';
            app.Hyperlink_3.Text = 'Help';
            app.MM1KTab = uitab(app.TabGroup);
            app.MM1KTab.Title = 'M/M/1/K';
            app.RUNButton_5 = uibutton(app.MM1KTab, 'push');
            app.RUNButton_5.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_5Pushed, true);
            app.RUNButton_5.Text = 'RUN';
            app.LambdaEditField_5Label = uilabel(app.MM1KTab);
            app.LambdaEditField_5Label.Text = 'Lambda';
            app.LambdaEditField_5 = uieditfield(app.MM1KTab, 'numeric');
            app.MuEditField_5Label = uilabel(app.MM1KTab);
            app.MuEditField_5Label.Text = 'Mu';
            app.MuEditField_5 = uieditfield(app.MM1KTab, 'numeric');
            app.maxreqEditField_2Label = uilabel(app.MM1KTab);
            app.maxreqEditField_2Label.Text = 'max req';
            app.maxreqEditField_2 = uieditfield(app.MM1KTab, 'numeric');
            app.IntroduceivalorileLabel_4 = uilabel(app.MM1KTab);
            app.IntroduceivalorileLabel_4.Text = 'Introduceți valorile';
            app.LambdareprezintratadesosireLabel_4 = uilabel(app.MM1KTab);
            app.LambdareprezintratadesosireLabel_4.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.MureprezintratadeservireLabel_4 = uilabel(app.MM1KTab);
            app.MureprezintratadeservireLabel_4.Text = 'Mu (μ) reprezintă rata de servire.';
            app.Image2_3 = uiimage(app.MM1KTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_1_K_poza.jpg');
            app.MaxreqreprezintanumrulmaximdecereriKLabel = uilabel(app.MM1KTab);
            app.MaxreqreprezintanumrulmaximdecereriKLabel.Text = 'Max req reprezinta numărul maxim de cereri (K)';
            app.Hyperlink_4 = uihyperlink(app.MM1KTab);
            app.Hyperlink_4.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_4Clicked, true);
            app.Hyperlink_4.Tag = 'HelpLabel_MM1K';
            app.Hyperlink_4.Text = 'Help';
            app.MMmmTab = uitab(app.TabGroup);
            app.MMmmTab.Title = 'M/M/m/m';
            app.RUNButton_6 = uibutton(app.MMmmTab, 'push');
            app.RUNButton_6.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_6Pushed, true);
            app.RUNButton_6.Text = 'RUN';
            app.LambdaEditField_6Label = uilabel(app.MMmmTab);
            app.LambdaEditField_6Label.Text = 'Lambda';
            app.LambdaEditField_6 = uieditfield(app.MMmmTab, 'numeric');
            app.MuEditField_6Label = uilabel(app.MMmmTab);
            app.MuEditField_6Label.Text = 'Mu';
            app.MuEditField_6 = uieditfield(app.MMmmTab, 'numeric');
            app.ServerNumEditField_3Label = uilabel(app.MMmmTab);
            app.ServerNumEditField_3Label.Text = 'ServerNum';
            app.ServerNumEditField_3 = uieditfield(app.MMmmTab, 'numeric');
            app.IntroduceivalorileLabel_5 = uilabel(app.MMmmTab);
            app.IntroduceivalorileLabel_5.Text = 'Introduceți valorile';
            app.LambdareprezintratadesosireLabel_5 = uilabel(app.MMmmTab);
            app.LambdareprezintratadesosireLabel_5.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.MureprezintratadeservireLabel_5 = uilabel(app.MMmmTab);
            app.MureprezintratadeservireLabel_5.Text = 'Mu (μ) reprezintă rata de servire.';
            app.Image2_4 = uiimage(app.MMmmTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_m_m_poza.jpg');
            app.Hyperlink_5 = uihyperlink(app.MMmmTab);
            app.Hyperlink_5.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_5Clicked, true);
            app.Hyperlink_5.Tag = 'HelpLabel_MMmm';
            app.Hyperlink_5.Text = 'Help';
            app.MMmKNTab = uitab(app.TabGroup);
            app.MMmKNTab.Title = 'M/M/m/K/N';
            app.RUNButton_7 = uibutton(app.MMmKNTab, 'push');
            app.RUNButton_7.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_7Pushed, true);
            app.RUNButton_7.Text = 'RUN';
            app.IntroduceivalorileLabel_6 = uilabel(app.MMmKNTab);
            app.IntroduceivalorileLabel_6.Text = 'Introduceți valorile';
            app.LambdareprezintratadesosireLabel_6 = uilabel(app.MMmKNTab);
            app.LambdareprezintratadesosireLabel_6.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.MureprezintratadeservireLabel_6 = uilabel(app.MMmKNTab);
            app.MureprezintratadeservireLabel_6.Text = 'Mu (μ) reprezintă rata de servire.';
            app.MaxreqreprezintanumrulmaximdecereriKLabel_3 = uilabel(app.MMmKNTab);
            app.MaxreqreprezintanumrulmaximdecereriKLabel_3.Text = 'Max req reprezinta numărul maxim de cereri (K)';
            app.Hyperlink_6 = uihyperlink(app.MMmKNTab);
            app.Hyperlink_6.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_6Clicked, true);
            app.Hyperlink_6.Tag = 'HelpLabel_MMmK';
            app.Hyperlink_6.Text = 'Help';
            app.LambdaEditField_7Label = uilabel(app.MMmKNTab);
            app.LambdaEditField_7Label.Text = 'Lambda';
            app.LambdaEditField_7 = uieditfield(app.MMmKNTab, 'numeric');
            app.MuEditField_7Label = uilabel(app.MMmKNTab);
            app.MuEditField_7Label.Text = 'Mu';
            app.MuEditField_7 = uieditfield(app.MMmKNTab, 'numeric');
            app.ServerNumEditField_4Label = uilabel(app.MMmKNTab);
            app.ServerNumEditField_4Label.Text = 'ServerNum';
            app.ServerNumEditField_4 = uieditfield(app.MMmKNTab, 'numeric');
            app.maxreqEditField_3Label = uilabel(app.MMmKNTab);
            app.maxreqEditField_3Label.Text = 'max req';
            app.maxreqEditField_3 = uieditfield(app.MMmKNTab, 'numeric');
            app.NrPopEditFieldLabel = uilabel(app.MMmKNTab);
            app.NrPopEditFieldLabel.Text = 'NrPop';
            app.NrPopEditField = uieditfield(app.MMmKNTab, 'numeric');
            app.Image2_6 = uiimage(app.MMmKNTab);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_m_K_N_poza.jpg');
            app.MEr1Tab = uitab(app.TabGroup);
            app.MEr1Tab.Title = 'M/Er/1';
            app.RUNButton_8 = uibutton(app.MEr1Tab, 'push');
            app.RUNButton_8.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_8Pushed, true);
            app.RUNButton_8.Text = 'RUN';
            app.LambdareprezintratadesosireLabel_7 = uilabel(app.MEr1Tab);
            app.LambdareprezintratadesosireLabel_7.Text = 'Lambda (λ) reprezintă rata de sosire.';
            app.Hyperlink_7 = uihyperlink(app.MEr1Tab);
            app.Hyperlink_7.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_7Clicked, true);
            app.Hyperlink_7.Text = 'Help';
            app.LambdaEditField_8Label = uilabel(app.MEr1Tab);
            app.LambdaEditField_8Label.Text = 'Lambda';
            app.LambdaEditField_8 = uieditfield(app.MEr1Tab, 'numeric');
            app.MuEditField_8Label = uilabel(app.MEr1Tab);
            app.MuEditField_8Label.Text = 'Mu';
            app.MuEditField_8 = uieditfield(app.MEr1Tab, 'numeric');
            app.rEditFieldLabel = uilabel(app.MEr1Tab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.MEr1Tab, 'numeric');
            app.rreprezintanumaruldefazeErlangLabel = uilabel(app.MEr1Tab);
            app.rreprezintanumaruldefazeErlangLabel.Text = 'r reprezinta numarul de faze Erlang';
            app.MureprezintratadeservireLabel_7 = uilabel(app.MEr1Tab);
            app.MureprezintratadeservireLabel_7.Text = 'Mu (μ) reprezintă rata de servire.';
            app.IntroduceivalorileLabel_7 = uilabel(app.MEr1Tab);
            app.IntroduceivalorileLabel_7.Text = 'Introduceți valorile';
            app.Image_2 = uiimage(app.MEr1Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'poze', 'M_M_1_poza_nou.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MM_QueueApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end